classdef brain2bot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CorrectLamp             matlab.ui.control.Lamp
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        InitializingRobotLabel  matlab.ui.control.Label
        GoodbyeLabel            matlab.ui.control.Label
        MissionCompleteLabel    matlab.ui.control.Label
        EXITButton              matlab.ui.control.Button
        HOMEButton              matlab.ui.control.Button
        ActionLabel             matlab.ui.control.Label
        Result                  matlab.ui.control.Label
        Target                  matlab.ui.control.Label
        ResultLabel             matlab.ui.control.Label
        TargetLabel             matlab.ui.control.Label
        CorrectLampLabel        matlab.ui.control.Label
        CountdownLabel          matlab.ui.control.Label
        VirtualModeButton       matlab.ui.control.Button
        RealRobotButton         matlab.ui.control.Button
        ReadyLabel              matlab.ui.control.Label
        BCIRobotArmLabel        matlab.ui.control.Label
        InteractLabel           matlab.ui.control.Label
        SkipTutorialButton      matlab.ui.control.Button
        StartTutorialButton     matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end

    properties (Access = public)
        TutorialButton matlab.ui.control.Button
        SkipButton matlab.ui.control.Button
        ReadyText matlab.ui.control.Label
        VirtualButton matlab.ui.control.Button
        RealButton matlab.ui.control.Button
    end
    
    properties (Access = private)
        % State variables
        MODE string;
        TEST logical = true;
        PERFECT logical = false;
        ERROR logical = false;
        StopTask logical = false;
        StopVideoPlayback logical = false; % flag for whether to stop/interrupt a video playback
        
        % UI Components
        HomeMenuComponents cell = {};
        ModeSelectionComponents cell = {};

        % Task variables
        Robot;  % Robot object
        Pos;   % Positions of objects (as given by AR module)
        PreloadedVideos = struct();
        VirtualModeClips = struct(...
            'Bottle', 'to_bottle.mp4', ...
            'Grasp', 'grasp_bottle.mp4', ...
            'Cup', 'to_cup.mp4', ...
            'Pour', 'pour.mp4', ...
            'Drink', 'drink.mp4', ...
            'BottleFail', 'initial.png', ...
            'GraspFail', 'grasp_bottle_fail.mp4', ...
            'CupFail', 'to_cup_fail.mp4', ...
            'PourFail', 'pour_fail.mp4', ...
            'DrinkFail', 'drink_fail.mp4' ...
            );
        Tasks = {'Bottle', 'Grasp', 'Cup', 'Pour', 'Drink'};  % Target task sequence
        MIs = {'Right', 'Grasp', 'Left', 'Twist', 'Left'};    % Corresponding MI
        Messages = {'Reaching to Bottle...', 'Grasping...', 'Reaching to Cup...', 'Pouring...', 'Retrieving Cup...'};
        ModelClasses = {'Left', 'Right', 'Grasp', 'Twist', 'Idle'};   % Classes used to pretrain classification model
    end
    
    % helper functions
    methods (Access = private)
        function ResetDisplay(app)
            % Get all properties of the app
            propNames = fieldnames(app);

            % List of components to always keep visible
            alwaysVisible = {'UIFigure', 'Image'};
            
            % Loop through each property and set its visibility to 'off' if it's a UI component
            for i = 1:length(propNames)
                propName = propNames{i};
                propValue = app.(propName);
                
                % Check if the current property is in the always visible list
                if ~ismember(propName, alwaysVisible) && ~isempty(propValue) && isprop(propValue, 'Visible')
                    propValue.Visible = 'off';
                end
            end
        end

        function DisplayModeSelection(app)
            % Hide initial components
            app.ResetDisplay();
            % Show the new components
            for component = app.ModeSelectionComponents
                component{1}.Visible = 'on';
            end
        end
        
        function DisplayHomeMenu(app)
            app.ResetDisplay();
            for component = app.HomeMenuComponents
                component{1}.Visible = 'on';
            end
        end
        
        function SetupAxesDisplay(app, contentWidth, contentHeight)
            % Set the fixed height
            fixedHeight = 720;
            
            % Calculate the aspect ratio of the video
            aspectRatio = contentWidth / contentHeight;
            
            % Calculate the width based on the fixed height and aspect ratio
            newWidth = fixedHeight * aspectRatio;
            
            % Adjust dimensions of the Panel
            app.Panel.Position(3) = newWidth-2;
            app.Panel.Position(4) = fixedHeight;

            % Adjust dimensions and position of the UIAxes
            app.UIAxes.Position = [0, 0, newWidth-2, fixedHeight];
            app.UIAxes.XLim = [0, contentWidth];
            app.UIAxes.YLim = [0, contentHeight];
        end


        function PlayVideo(app, fname)
            app.Panel.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.StopVideoPlayback = false;

            cwd = fileparts(mfilename('fullpath'));
            filePath = fullfile(cwd, 'videos', fname);
            vidObj = VideoReader(filePath);
            
            SetupAxesDisplay(app, vidObj.Width, vidObj.Height);

            while hasFrame(vidObj) && ~app.StopVideoPlayback
                vidFrame = readFrame(vidObj);
                image(vidFrame, 'Parent', app.UIAxes); 
                pause(1/vidObj.FrameRate);
            end

        end

        function DisplayVisualFeedback(app, fname)
            if app.StopTask
                return;
            end
            cwd = fileparts(mfilename('fullpath'));
            app.Panel.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.HOMEButton.Visible = "on";
            app.StopVideoPlayback = false;

            % Check if the file is an image (.jpg)
            [~,~,ext] = fileparts(fname);
            if ismember(ext, {'.jpg', '.png'})
                img = imread(fullfile(cwd, 'videos/480', fname));
                % Adjust UIAxes dimensions and centering
                SetupAxesDisplay(app, size(img, 2), size(img, 1));
                imshow(img, 'Parent', app.UIAxes);
                pause(2);
                return;
            end
            
            fieldname = strrep(fname, '.mp4', '');
            vidFrames = app.PreloadedVideos.(fieldname).frames;
            framerate = app.PreloadedVideos.(fieldname).framerate;
            SetupAxesDisplay(app, size(vidFrames, 2), size(vidFrames, 1));

            % Play video frame by frame
            for i = 1:size(vidFrames, 4)
                if app.StopVideoPlayback || app.StopTask
                    break;
                end
                imshow(vidFrames(:,:,:,i), 'Parent', app.UIAxes);
                pause(1/framerate);
            end


        end

        function i = DisplayResults(app, i, pred, actual, task, msg)
            % Display the classification result
            app.Result.Text = pred;
            
            % Check the output against the expected MI
            if strcmp(pred, actual)
                % Update lamp
                app.CorrectLamp.Color = 'green';
                app.CorrectLampLabel.Text = 'Correct!';
                app.CorrectLampLabel.FontColor = 'green';
                app.ActionLabel.Text = msg;

                % Display results
                if ~app.StopTask
                    app.Result.Visible = "on";
                    pause(0.5);
                    app.CorrectLamp.Visible = "on";
                    app.CorrectLampLabel.Visible = "on";
    %                     pause(0.5);
                    app.ActionLabel.Visible = "on";
                end
                
                if strcmp(app.MODE, 'Real')
                    pause(1);
                    try
                        PerformRealTask(app.Robot, task, app.Pos, false);
                    catch ME
                        app.HandleError(ME);
                    end
                end
                if strcmp(app.MODE, 'Virtual')
                    % Play the corresponding video
                    video = app.VirtualModeClips.(task);
                    app.DisplayVisualFeedback(video);
                end

                i = i + 1;
                
            else
                % Update lamp
                app.CorrectLamp.Color = 'red';
                app.CorrectLampLabel.Text = 'Incorrect!';
                app.CorrectLampLabel.FontColor = 'red';
                app.ActionLabel.Text = 'Returning to Step 1...';

                % Display results
                app.Result.Visible = "on";
                pause(0.5);
                app.CorrectLamp.Visible = "on";
                app.CorrectLampLabel.Visible = "on";
%                     pause(0.5);
                app.ActionLabel.Visible = "on";

                if strcmp(app.MODE, 'Real')
                    try
                        PerformRealTask(app.Robot, task, app.Pos, true);
                    catch ME
                        app.HandleError(ME);
                    end
                end
                if strcmp(app.MODE, 'Virtual')
                    % Play the "fail" video
                    video = app.VirtualModeClips.([task 'Fail']);
                    app.DisplayVisualFeedback(video);
                end

                % Reset to the beginning of the sequence
                i = 1;
            end

            % Turn off results display
            app.CorrectLamp.Visible = "off";
            app.CorrectLampLabel.Visible = "off";
            app.ActionLabel.Visible = "off";
            app.Result.Visible = "off";
            app.Target.Visible = "off";
        end

        function [state, model] = Initialize(app)
            % Mute all buttons and objects
            app.StopTask = false;
            app.StopVideoPlayback = false;

            app.ResetDisplay();
            app.InitializingRobotLabel.Text = "Initializing Robot...";
            app.InitializingRobotLabel.Visible = "on";

            if strcmp(app.MODE, 'Real')
                app.Robot = JacoComm;
                connect(app.Robot);
                InitializeRobot(app.Robot);
                endEffectorOrientation = app.Robot.EndEffectorPose(4:6);
                app.Pos = GetARCoordinates(endEffectorOrientation);
            end

            if strcmp(app.MODE, 'Virtual')
                % Preload videos in the background
    %             preload = parfeval(pool, @robot_arm_control.PreloadVideos, 1);
                app.PreloadVideos();
            end

            % Start countdown for user
            app.Countdown(3);
            % Main Task init
            state = struct;
            if app.TEST
                model = 1;
            else
                bbci_acquire_bv('close');
                state = bbci_acquire_bv('init', state);
                model = createEEGNet(32, 5, 750); 
                load('learnableParams.mat', 'learnableParams');
                model.Learnables = learnableParams;
            end

        end

        function HandleError(app, ME)
            % Display the error message in a dialog box
            errorMessage = sprintf('Error occurred: %s\n\nWould you like to return home or exit the program?', ME.message);
            choice = questdlg(errorMessage, 'Error', 'Return Home', 'Exit', 'Return Home');
            app.ERROR = true;
            
            switch choice
                case 'Return Home'
                    app.HOMEButtonPushed();
                    app.ERROR = false;
                case 'Exit'
                    app.EXITButtonPushed();
                    app.ERROR = false;
                    return;
            end
        end

        function PreloadVideos(app)
            cwd = fileparts(mfilename('fullpath'));
            videoDir = fullfile(cwd, 'videos/480');
            
            % Get a list of all the video files in the directory
            videoFiles = dir(fullfile(videoDir, '*.mp4'));

            for i = 1:length(videoFiles)
                fname = videoFiles(i).name;
                vidObj = VideoReader(fullfile(videoDir, fname));
        
                vidFrames = zeros(vidObj.Height, vidObj.Width, 3, vidObj.NumFrames, 'uint8');
                idx = 1;
                while hasFrame(vidObj)
                    vidFrames(:,:,:,idx) = readFrame(vidObj);
                    idx = idx + 1;
                end
                fieldname = strrep(fname, '.mp4', '');
                app.PreloadedVideos.(fieldname).frames = vidFrames;
                app.PreloadedVideos.(fieldname).duration = vidObj.Duration;
                app.PreloadedVideos.(fieldname).framerate = vidObj.FrameRate;

                 % Update the label with changing number of dots
%                 numDots = mod(i, 4) + 1;  % cycle between 1 and 4 dots
                app.InitializingRobotLabel.Text = ['Initializing Robot', repmat('.', 1, 3+i)];
            end
        end

        function Countdown(app, maxCount)
            app.InitializingRobotLabel.Visible = 'off';
            app.CountdownLabel.Position(1) = (app.UIFigure.Position(3) - app.CountdownLabel.Position(3)) / 2;
            app.CountdownLabel.Position(2) = (app.UIFigure.Position(4) - app.CountdownLabel.Position(4)) / 2; % Roughly centered
            app.CountdownLabel.Visible = "on";

            % Countdown from 3 to 1
            for count = maxCount:-1:1
                app.CountdownLabel.Text = num2str(count);
                pause(1);
            end

            app.CountdownLabel.Visible = "off";

        end
        
        function PerformMainTask(app, state, model)
            if ~app.StopTask
                app.ResultLabel.Visible = "on";
                app.TargetLabel.Visible = "on";
                app.HOMEButton.Visible = "on";
            end
            
            if app.TEST
                % Generate the preds list for testing all possibilities
                if app.PERFECT
                    targets = {1, 2, 3, 4, 5};
                    preds = {1, 2, 3, 4, 5};
                else
                    targets = {1, 1, 2, 1, 2, 3, 1, 2, 3, 4, 1, 2, 3, 4, 5, 1, 2, 3, 4, 5};
                    preds =   {2, 1, 5, 1, 2, 4, 1, 2, 3, 1, 1, 2, 3, 4, 1, 1, 2, 3, 4, 5};
                end
                for i = 1:length(targets)
                    if app.StopTask
                        break;
                    end
                    target = targets{i};
                    app.Target.Text = app.MIs{target};
                    app.Target.Visible = "on";
                    pred = preds{i};
                    pause(4);
                    taskName = app.Tasks{target};
                    msg = app.Messages{targets{i}};
                    app.DisplayResults(targets{i}, app.MIs{pred}, app.MIs{target}, taskName, msg);
                end
            else
                i = 1;
                while i <= length(app.Tasks) && ~ app.StopTask
                    app.Target.Text = app.MIs{i};
                    app.Target.Visible = "on";
    
                    % Acquire EEG data for 3 seconds
                    data = EEGSignalAcqusition(state, 749);  % includes pause
                    
                    % Process the data with the neural network model
                    output = forward(model, dlarray(data.', 'SSCB'));  % transpose data first
                    [~, pred] = max(output, [], 'all');
                    if app.PERFECT
                        preds = {2,3,1,4,1};
                        pred = preds{i};
                    end
                    i = app.DisplayResults(i, app.ModelClasses{pred}, app.MIs{i}, app.Tasks{i}, app.Messages{i});
                end
            end

            if ~ app.StopTask  
                % End task gracefully
                app.ResetDisplay();
                app.MissionCompleteLabel.Visible = "on";
                pause(3);
                app.DisplayHomeMenu();   
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HomeMenuComponents = {
                app.BCIRobotArmLabel, app.StartTutorialButton, ...
                app.SkipTutorialButton, app.EXITButton, ...
                app.InteractLabel
            };
        
            app.ModeSelectionComponents = {
                app.ReadyLabel, ...
                app.VirtualModeButton, ...
                app.RealRobotButton
            };
            
            
            % Get screen resolution
            screenSize = get(0, 'ScreenSize');
            w = screenSize(3);
            h = screenSize(4);
%             app.UIFigure.Position = [w*0.1 h*0.1 w*0.8 h*0.8];
            
            % Create a figure that occupies the full screen
            app.UIFigure.Position = [1 1 w h];


        end

        % Button pushed function: StartTutorialButton
        function StartTutorialButtonPushed(app, event)
            %app.PlayVideo('Tutorial_lowres.mp4');
            app.PlayVideo('to_cup_fail.mp4'); %'Tutorial_lowres.mp4');
            app.DisplayModeSelection();
        end

        % Button pushed function: SkipTutorialButton
        function SkipTutorialButtonPushed(app, event)
            app.StopVideoPlayback = true;
            app.DisplayModeSelection();
        end

        % Button pushed function: VirtualModeButton
        function VirtualModeButtonPushed(app, event)
            app.MODE = 'Virtual';
            try
                [state, model] = app.Initialize();
            catch ME
                app.HandleError(ME);
            end
            app.DisplayVisualFeedback('initial.png'); 
            app.PerformMainTask(state, model);
        end

        % Button pushed function: RealRobotButton
        function RealRobotButtonPushed(app, event)
            app.MODE = 'Real'; 
            try
                [state, model] = app.Initialize();
            catch ME
                app.HandleError(ME);
            end
            app.PerformMainTask(state, model);
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            if ~app.ERROR
                % confirm with the user
                choice = questdlg('Return to home menu?', ...
                    'Confirmation', ...
                    'Yes', 'No', 'No');
                if strcmp(choice, 'No')
                    return; % If user chooses 'No', don't proceed
                end
            end

            app.StopTask = true;
            app.StopVideoPlayback = true;
            app.ResetDisplay();
            app.DisplayHomeMenu();
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            if ~app.ERROR
                % confirm with the user
                choice = questdlg('Are you sure you want to exit?', ...
                    'Exit Confirmation', ...
                    'Yes', 'No', 'No');
                if strcmp(choice, 'No')
                    return; % If user chooses 'No', don't proceed with exit
                end
            end

            app.StopTask = true;
            app.StopVideoPlayback = true;

            app.ResetDisplay();
            app.GoodbyeLabel.Visible = "on";
            
            if strcmp(app.MODE, 'Real')
                InitializeRobot(app.Robot);
                disconnect(app.Robot);
            end
            
            if ~app.TEST
                bbci_acquire_bv('close');
            end

            % Shutdown app
            pause(2);
            delete(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if strcmp(event.Key, 'f')
                app.UIFigure.WindowState = 'fullscreen';
            end
            % Check for Ctrl+C
            if strcmp(event.Key, 'c') && any(strcmp(event.Modifier, 'control'))
                app.EXITButtonPushed();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'abstract-luxury-gradient-blue-background-smooth-dark-blue-with-black-vignette-studio-banner.jpg');
            app.StartTutorialButton = uibutton(app.UIFigure, 'push');
            app.StartTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @StartTutorialButtonPushed, true);
            app.StartTutorialButton.Text = 'Start Tutorial';
            app.SkipTutorialButton = uibutton(app.UIFigure, 'push');
            app.SkipTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @SkipTutorialButtonPushed, true);
            app.SkipTutorialButton.Text = 'Skip Tutorial';
            app.InteractLabel = uilabel(app.UIFigure);
            app.InteractLabel.Text = 'Interact with a robot arm using your brain waves!';
            app.BCIRobotArmLabel = uilabel(app.UIFigure);
            app.BCIRobotArmLabel.Text = 'BCI Robot Arm';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Visible = 'off';
            app.ReadyLabel.Text = 'Ready? Now let''s get interactive!';
            app.RealRobotButton = uibutton(app.UIFigure, 'push');
            app.RealRobotButton.ButtonPushedFcn = createCallbackFcn(app, @RealRobotButtonPushed, true);
            app.RealRobotButton.Visible = 'off';
            app.RealRobotButton.Tooltip = {'Interact with a physical robot in front of you'};
            app.RealRobotButton.Text = 'Real Robot';
            app.VirtualModeButton = uibutton(app.UIFigure, 'push');
            app.VirtualModeButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualModeButtonPushed, true);
            app.VirtualModeButton.Visible = 'off';
            app.VirtualModeButton.Tooltip = {'Interact in virtual mode to get an experience'};
            app.VirtualModeButton.Text = 'Virtual Mode';
            app.CountdownLabel = uilabel(app.UIFigure);
            app.CountdownLabel.Enable = 'off';
            app.CountdownLabel.Visible = 'off';
            app.CountdownLabel.Text = '3';
            app.CorrectLampLabel = uilabel(app.UIFigure);
            app.CorrectLampLabel.Visible = 'off';
            app.CorrectLampLabel.Text = 'Correct!';
            app.TargetLabel = uilabel(app.UIFigure);
            app.TargetLabel.Visible = 'off';
            app.TargetLabel.Text = 'Target';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.Target = uilabel(app.UIFigure);
            app.Target.Visible = 'off';
            app.Target.Text = '"Target"';
            app.Result = uilabel(app.UIFigure);
            app.Result.Visible = 'off';
            app.Result.Text = '"Result"';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Visible = 'off';
            app.ActionLabel.Text = 'Action';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Visible = 'off';
            app.HOMEButton.Text = 'HOME';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Visible = 'off';
            app.EXITButton.Text = 'EXIT';
            app.MissionCompleteLabel = uilabel(app.UIFigure);
            app.MissionCompleteLabel.Visible = 'off';
            app.MissionCompleteLabel.Text = 'Mission Complete!';
            app.GoodbyeLabel = uilabel(app.UIFigure);
            app.GoodbyeLabel.Visible = 'off';
            app.GoodbyeLabel.Text = 'Thank you for using the app. Goodbye!';
            app.InitializingRobotLabel = uilabel(app.UIFigure);
            app.InitializingRobotLabel.Visible = 'off';
            app.InitializingRobotLabel.Text = 'Initializing Robot...';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.CorrectLamp = uilamp(app.UIFigure);
            app.CorrectLamp.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brain2bot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end